classdef SpheraSense < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PredictionforListBox            matlab.ui.control.ListBox
        LabelstoshowListBoxLabel        matlab.ui.control.Label
        AddanoteLabel                   matlab.ui.control.Label
        AutomaticanalysisButton         matlab.ui.control.Button
        FillHolesCheckBox               matlab.ui.control.CheckBox
        AddanotetocurrentimageEditField  matlab.ui.control.EditField
        ProbabilitythresholdSlider      matlab.ui.control.Slider
        ProbabilitythresholdSliderLabel  matlab.ui.control.Label
        Binlabel                        matlab.ui.control.Label
        MLlabel                         matlab.ui.control.Label
        MLimage                         matlab.ui.control.Image
        UpdateButton                    matlab.ui.control.Button
        SelectfolderButton              matlab.ui.control.Button
        ZplaneLabel                     matlab.ui.control.Label
        WellListBox                     matlab.ui.control.ListBox
        WellLabel                       matlab.ui.control.Label
        ZplaneListBox                   matlab.ui.control.ListBox
        ChannelListBox                  matlab.ui.control.ListBox
        ChannelLabel                    matlab.ui.control.Label
        ImageLabel                      matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        StateLabel                      matlab.ui.control.Label
        PreviousButton                  matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        RunMLmodelButton                matlab.ui.control.Button
        WritevaluestoresultsfileButton  matlab.ui.control.Button
        ExportresultsButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes1                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        folder_path = '';
        probfiles_path = '';
        ilastik_proj_path = '';

        filenames_data = struct;
        analysis = table();
        output_table = table();

        img_index = 1;
        note = "";
    end
    
    methods (Access = private)
        
        function img_formatted = format_img(~, img)
           % Image format conversion for display

           % uint16 -> uint8
            if isa(img, 'uint16')
                img_uint8 = uint8(double(img) / double(max(img(:))) * 255);     
            else
                img_uint8 = im2uint8(img); % catch-all for other datatypes
            end
            
            % grayscale -> RGB
            if size(img_uint8, 3) == 1
                img_formatted = repmat(img_uint8, [1 1 3]);
            else
                img_formatted = img_uint8;
            end
        end
        
        % get pixel dimensions in microns
        function micronarea = pixelarea(~,metadata)

            width_pixels = XMLStringToVariable(metadata).ImageAcquisition.PixelWidth;
            height_pixels = XMLStringToVariable(metadata).ImageAcquisition.PixelWidth;
        
            width_microns = XMLStringToVariable(metadata).ImageAcquisition.ImageWidthMicrons;
            height_microns = XMLStringToVariable(metadata).ImageAcquisition.ImageHeightMicrons;
            
            S_pixels = str2double(width_pixels)*str2double(height_pixels);
            S_microns = str2double(width_microns)*str2double(height_microns);
            micronarea = S_microns/S_pixels;
            % get area of one pixel in microns(^2)
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
           
            format longG % avoid scientific notation
            if ~isempty(app.output_table)
                
                choice = uiconfirm(app.UIFigure, ...
                    'Previous data is unsaved.', ...
                    'Unsaved Data Warning', ...
                    'Options', {'Discard it', 'Save it'}, ...
                    'CancelOption', 'Discard it');
                
                if strcmp(choice, 'Discard it')
                    app.output_table = table();
                    app.img_index = 1;
                    
                elseif strcmp(choice, 'Save it')
                    ExportresultsButtonPushed(app, event);
                end
            end

            app.folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BSc_thesis');
            if app.folder_path == 0
                app.StateLabel.Text = 'No folder selected.';
                return;
            end
            app.StateLabel.Text = ['Folder selected: ', app.folder_path];
                
            % Get filenames_data, detect weird filenames
            [app.filenames_data, nonmatch_files] = dataset_handling(app.folder_path);
            
            if isempty(app.filenames_data)
                app.StateLabel.Text = ['No microscopy images in the selected folder.', newline, ...
                                       'Please choose again.'];
                return
            else
                app.StateLabel.Text = '';
            end

            if numel(nonmatch_files) > 0
                app.StateLabel.Text = sprintf('Warning: excluded %d files with an unexpected name:\n%s', numel(nonmatch_files), strjoin(nonmatch_files, '\n'));
            end

            % Load in well options
            wellIDs = {app.filenames_data.WellID};
            uniqueIDs = unique(wellIDs, 'stable');
            app.WellListBox.Items = cellstr(string(uniqueIDs));

            % Load in Zplane options
            %zplane_values = unique(cell2mat({app.filenames_data.ZPlane}));

            zplanes = app.filenames_data(strcmp(wellIDs, wellIDs(1))).ZPlane;
            zplane_values = unique(zplanes, 'stable');
            app.ZplaneListBox.Items = string(zplane_values);

            % Load in channels available for the first well
            well_indices = find(strcmp({app.filenames_data.WellID}, app.WellListBox.Value));
            well_data = app.filenames_data(well_indices);
            app.ChannelListBox.Items = unique({well_data.ChannelName});
            app.ChannelListBox.Value = app.filenames_data(app.img_index).ChannelName;

            app.output_table = table('Size', [0 23], ...,
                              'VariableTypes', {'string', 'string', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'double','double', 'double', 'double', 'double', 'double', 'double', 'double', 'double', 'string'}, ...
                              'VariableNames', {'Filename', 'WellID', 'ZPlane', 'Area(px)', 'Area(microns)', 'Diameter(px)', 'Diameter(microns)', 'Perimeter(px)','Perimeter(microns)', 'Circularity', 'RFP intensity mean', 'RFP standard deviation', 'RFP max', 'RFP min', 'CY5 intensity mean', 'CY5 standard deviation', 'CY5 max', 'CY5 min', 'DAPI intensity mean', 'DAPI standard deviation', 'DAPI max', 'DAPI min', 'Note'});  

            % Display first well of folder
            image1 = imread(app.filenames_data(1).FilePath);
            app.Image.ImageSource = format_img(app, image1);
            app.ImageLabel.Text = app.filenames_data(1).FileName;
            app.ProbabilitythresholdSlider.Value = 80;

            app.probfiles_path = app.folder_path + "_Probabilities";

            % if not ML analysed
            if ~isfolder(app.probfiles_path) || isempty(dir(fullfile(app.probfiles_path, '*.h5')))
                app.StateLabel.Text = "This folder is not yet analysed. Click the Run ML button.";
                return
            end
            
            % if already ML analysed, pull up probability files % to-do: check if partially analysed, avoid going from zero then
            if isfolder(app.probfiles_path) && ~isempty(dir(fullfile(app.probfiles_path, '*.h5')))

                choice = questdlg('I see this folder is analysed. Should I assume you have used the Default_mini Ilastik model?', 'Model used for analysis', ...
                  'Yes', 'No, I will specify the model used', 'Yes');

                switch choice
                    case 'Yes'
                        MLmodel_labels = h5read('Default_mini.ilp', '/PixelClassification/LabelNames');
                        app.PredictionforListBox.Items = MLmodel_labels.';

                    case 'No, I will specify the model used'
                        clear modelname pathname;
                        [modelname, pathname] = uigetfile({'*.ilp', 'ilastik Project Files (*.ilp)'; '*.*', 'All Files (*.*)'}, 'Select the ML model');
                        app.ilastik_proj_path = fullfile(pathname, modelname);
                        MLmodel_labels = h5read(app.ilastik_proj_path, '/PixelClassification/LabelNames');
                        app.PredictionforListBox.Items = MLmodel_labels.';

                        % if there is a spheroid label, select it straight away
                        spheroidlabel = contains(MLmodel_labels, 'Spheroid', 'IgnoreCase', true);
            
                        if any(spheroidlabel)
                            choice_index = find(spheroidlabel, 1);
                            app.PredictionforListBox.Value = app.PredictionforListBox.Items{choice_index};
                        end

                    otherwise
                        disp('User closed the dialog.');
                end               
            end

            UpdateButtonPushed(app, event);
        end

        % Value changed function: WellListBox
        function WellListBoxValueChanged(app, event)
                        
            % All data regarding chosen well
            value = app.WellListBox.Value;
            well_indices = find(strcmp({app.filenames_data.WellID}, value));
            well_data = app.filenames_data(well_indices);

            % Zplanes available for chosen well
            zplane_values = unique([well_data.ZPlane]);
            app.ZplaneListBox.Items = string(zplane_values);

            % Display first image of chosen well
            app.img_index = well_indices(1);
            img = imread(app.filenames_data(app.img_index).FilePath);
            app.Image.ImageSource = app.format_img(img);
            app.ImageLabel.Text = app.filenames_data(app.img_index).FileName;

            UpdateButtonPushed(app, event)
        end

        % Value changed function: ZplaneListBox
        function ZplaneListBoxValueChanged(app, event)

            % Stay on the same well indexes but change Z index
            
            well_value = app.WellListBox.Value;
            wellIDs = {app.filenames_data.WellID};
            well_match = app.filenames_data(strcmp(wellIDs, well_value)); % rows with chosen WellID

            z_value = str2double(app.ZplaneListBox.Value);
            z_match = well_match([well_match.ZPlane] == z_value); % right well + Zplane

            img = imread(z_match.FilePath);
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            app.ImageLabel.Text = z_match.FileName;
            app.img_index = find(strcmp({app.filenames_data.FileName}, z_match.FileName));
           
            UpdateButtonPushed(app,event)
        end

        % Value changed function: ChannelListBox
        function ChannelListBoxValueChanged(app, event)
            
%             current_img = app.filenames_data(app.img_index).FileName;              %'A1_03_1_1_Bright Field_003.tif'
%             current_channel = app.filenames_data(app.img_index).ChannelName;
%             current_channelindex = app.filenames_data(app.img_index).ChannelIndex; 
%             
%             channel_value = app.ChannelListBox.Value;                                % 'RFP'
%             channel_index = strcmp({app.filenames_data.ChannelName}, channel_value); 
%             channel_index = find(channel_index, 1);                                  % Get channelIndex of chosen channel
% 
%             match_index = find(strcmp({app.filenames_data.ChannelName}, channel_value) & ...
%                               [app.filenames_data.ChannelIndex] == channel_index_value);


%             new_img = strrep(current_img, current_channel, channel_value);           %'A1_03_1_1_RFP_003.tif'
%             new_img = strrep(new_img, num2str(current_channelindex), num2str(channel_index)); %'A1_03_2_1_RFP_003.tif'
%             

%             img = imread(app.filenames_data(app.img_index).FilePath);
%             app.Image.ImageSource = app.format_img(img);
% 
%             app.ImageLabel.Text = app.filenames_data(app.img_index).FileName;
%             UpdateButtonPushed(app,event)
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            
            if app.img_index == numel(app.filenames_data)
                app.StateLabel.Text = "Last image of the folder.";
            else
                app.img_index = app.img_index + 1;
                app.ImageLabel.Text = app.filenames_data(app.img_index).FileName;
                image_next = imread(app.filenames_data(app.img_index).FilePath);
                app.Image.ImageSource = app.format_img(image_next);

                UpdateButtonPushed(app, event)     
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)

            if app.img_index == 1
                app.StateLabel.Text = "First image of the folder.";
            else
                app.img_index = app.img_index - 1;
                app.ImageLabel.Text = app.filenames_data(app.img_index).FileName;
                image_previous = imread(app.filenames_data(app.img_index).FilePath);
                app.Image.ImageSource = app.format_img(image_previous);

                UpdateButtonPushed(app, event)                         
            end
        end

        % Button pushed function: RunMLmodelButton
        function RunMLmodelButtonPushed(app, event)
            %defaultFolder = 'C:\Users\Kasutaja\Desktop\BSc_thesis\SpheraSense_repo';
            %[modelname, pathname] = uigetfile({'*.ilp', 'ilastik Project Files (*.ilp)'; '*.*', 'All Files (*.*)'}, 'Select an ilastik File', defaultFolder);

            %ilastik_proj_path = "C:\Users\Kasutaja\Desktop\BSc_thesis\SpheraSense_repo\Default_mini.ilp";

            [modelname, pathname] = uigetfile({'*.ilp', 'ilastik Project Files (*.ilp)'; '*.*', 'All Files (*.*)'}, 'Select an ilastik File');

            if isequal(modelname,0) || isequal(pathname,0)
                uialert(app.UIFigure, 'Please select an ilastik ML model', 'Model choice', 'Icon', 'none');
                return;
            else
                app.StateLabel.Text = ['Selected model: ', modelname];
                app.ilastik_proj_path = fullfile(pathname, modelname);
            end

            if isempty(app.folder_path)
                uialert(app.UIFigure, 'Please select an image folder to analyse', 'Folder choice', 'Icon', 'none');
                return;
            end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Processing', 'Message', 'Starting Ilastik...');
            run_ilastik(app.folder_path, app.ilastik_proj_path, progress)

            app.StateLabel.Text = "Images analysed with ML.";
            app.probfiles_path = app.folder_path + "_Probabilities";

            MLmodel_labels = h5read(app.ilastik_proj_path, '/PixelClassification/LabelNames');
            app.PredictionforListBox.Items = MLmodel_labels.';

            % if there is a spheroid label, select it straight away
            spheroidlabel = contains(MLmodel_labels, 'Spheroid', 'IgnoreCase', true);

            if any(spheroidlabel)
                choice_index = find(spheroidlabel, 1);
                app.PredictionforListBox.Value = app.PredictionforListBox.Items{choice_index};
            end
            
            UpdateButtonPushed(app, event)
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            
            format longG % avoid scientific notation
            
            % Update Well 
            app.WellListBox.Value = app.filenames_data(app.img_index).WellID;
            well_indices = find(strcmp({app.filenames_data.WellID}, app.WellListBox.Value));
            well_data = app.filenames_data(well_indices);
            
            % Update Zplanes available for chosen well
            zplane_values = unique([well_data.ZPlane]);
            app.ZplaneListBox.Items = string(zplane_values);
            app.ZplaneListBox.Value = string(app.filenames_data(app.img_index).ZPlane);

            % Update channels available
            channels = unique({well_data.ChannelName});
            app.ChannelListBox.Items = channels;
            app.ChannelListBox.Value = app.filenames_data(app.img_index).ChannelName;
            
            % Break if no h5 files
            if ~isfolder(app.probfiles_path) || isempty(dir(fullfile(app.probfiles_path, '*.h5')))
                return
            end
            
            % Look for h5 prob files available
            h5 = dir(fullfile(app.probfiles_path, '*.h5'));
            h5names = {h5.name};
            label = app.ImageLabel.Text;
            [~, labelname, ~] = fileparts(app.ImageLabel.Text); % [path, name, extension]
            
            % Display BF probability file (h5)
            for n = 1:length(h5names)

                if contains(h5names(n), labelname)
                    h5_selected = h5names(n);      
                    
                elseif contains(labelname, 'RFP') || contains(labelname, 'CY5') || contains(labelname, 'DAPI') % look for same image in BF
                    current_img = match_to_BF(labelname, app.filenames_data); 
                    parts =  regexp(current_img, '\.', 'split');
                    h5_selected = [parts{1}, '_Probabilities.h5'];
                    h5_selected = {h5_selected};
                end
            end
            
            h5_path = fullfile((app.probfiles_path), h5_selected);
            data = h5read(h5_path{1}, '/exported_data');
            show = permute(data,[3,2,1]);     % cxy to yxc

            labelchoice_index = find(strcmp(app.PredictionforListBox.Items, app.PredictionforListBox.Value));
            spheroid_probmap = show(:, :, labelchoice_index);

            threshold = app.ProbabilitythresholdSlider.Value / 100;
            binaryimage = spheroid_probmap >= threshold;

            if app.FillHolesCheckBox.Value == 1
                binaryimage = imfill(binaryimage, 'holes');
            end

            mask = get_mask(binaryimage);
                                           
            if contains(labelname, 'RFP') % if RFP image, quantify fluorescence
                
                current_img = [current_img, '.tif'];
                rfpindex = find(strcmp({app.filenames_data.FileName}, label));
                RFP = imread(app.filenames_data(rfpindex).FilePath); % type uint16

                RFPimg = double(RFP);     % linearizes
                RFPsphval = RFPimg(mask); % apply mask to RFP img
                RFP_mean = mean(RFPsphval); 
                RFP_std = std(RFPsphval);
                RFP_max = max(RFPsphval);
                RFP_min = min(RFPsphval);
            else
                RFP_mean = 0;
                RFP_std = 0;
                RFP_max = 0;
                RFP_min = 0;
            end

            if contains(labelname, 'CY5') % if CY5 image, quantify fluorescence
                
                current_img = [current_img, '.tif'];
                CY5index = find(strcmp({app.filenames_data.FileName}, label));
                CY5 = imread(app.filenames_data(CY5index).FilePath); % type uint16

                CY5img = double(CY5);     % linearizes
                CY5sphval = CY5img(mask); % apply mask to RFP img
                CY5_mean = mean(CY5sphval); 
                CY5_std = std(CY5sphval);
                CY5_max = max(CY5sphval);
                CY5_min = min(CY5sphval);
            else
                CY5_mean = 0;
                CY5_std = 0;
                CY5_max = 0;
                CY5_min = 0;
            end

            if contains(labelname, 'DAPI') % if DAPI image, quantify fluorescence
                
                current_img = [current_img, '.tif'];
                dapiindex = find(strcmp({app.filenames_data.FileName}, label));
                DAPI = imread(app.filenames_data(dapiindex).FilePath); % type uint16
            
                DAPIimg = double(DAPI);     % linearizes
                DAPIsphval = DAPIimg(mask); % apply mask to DAPI img
                DAPI_mean = mean(DAPIsphval); 
                DAPI_std = std(DAPIsphval);
                DAPI_max = max(DAPIsphval);
                DAPI_min = min(DAPIsphval);
            else
                DAPI_mean = 0;
                DAPI_std = 0;
                DAPI_max = 0;
                DAPI_min = 0;
            end
        
            
            spheroid_pixels = sum(mask(:)); % Count spheroid pixels
            
            img_path = [fullfile(app.folder_path), '\',app.ImageLabel.Text];
            metadata = imfinfo(img_path).ImageDescription;
            spheroid_area = spheroid_pixels * pixelarea(app, metadata);

            % Graph circumference + diameter of spheroid
            
            imshow(mask, 'Parent', app.UIAxes);
            app.UIAxes.XColor = 'none';  
            app.UIAxes.YColor = 'none';
            app.UIAxes.Color = 'none';
            app.UIAxes.XLim = [1 size(binaryimage, 2)];
            app.UIAxes.YLim = [1 size(binaryimage, 1)];
            app.UIAxes.YDir = 'reverse'; % match image coordinates
            hold(app.UIAxes, 'on');
            
            % Compute the convex hull + extract boundaries
            convex_hull = bwconvhull(mask);            % Compute the convex hull
            boundaries = bwboundaries(convex_hull);    % Extract boundaries of the convex hull
            boundary = boundaries{1};                  % Get the first boundary (outermost boundary)
            
            x = boundary(:, 2);                           % Col 2: x-coordinates
            y = boundary(:, 1);                           % Col 1: y-coordinates
            plot(app.UIAxes, x, y, 'r-', 'LineWidth', 1); % Convex hull outline

            distances = pdist2(boundary, boundary);     % Pairwise distances between all boundary points
            bound_dist = sqrt(diff(x).^2 + diff(y).^2); % Distances between consecutive boundary points
            perimeter = sum(bound_dist);
            perimeter_microns = perimeter * sqrt(pixelarea(app, metadata));
            [~, max_idx] = max(distances(:));           % Boundary points furthest apart
            [max_row, max_col] = ind2sub(size(distances), max_idx);

            diameter = distances(max_row, max_col);     % Length
            diameter_microns = diameter * sqrt(pixelarea(app, metadata));
            
            circularity = (4*pi*spheroid_area)/perimeter_microns^2;

            plot(app.UIAxes, [boundary(max_row, 2), boundary(max_col, 2)], ...
                             [boundary(max_row, 1), boundary(max_col, 1)], ...
                             'g-', 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            
            
            % analysis = tablerow data of current spheroid
            app.analysis = table(string(labelname), string(app.filenames_data(app.img_index).WellID), app.filenames_data(app.img_index).ZPlane, spheroid_pixels, spheroid_area, diameter, diameter_microns, perimeter, perimeter_microns, circularity, RFP_mean, RFP_std, RFP_max, RFP_min, CY5_mean, CY5_std, CY5_max, CY5_min, DAPI_mean, DAPI_std, DAPI_max, DAPI_min, app.note, ...
                      'VariableNames', {'Filename', 'WellID', 'ZPlane', 'Area(px)', 'Area(microns)', 'Diameter(px)', 'Diameter(microns)', 'Perimeter(px)','Perimeter(microns)', 'Circularity', 'RFP intensity mean', 'RFP standard deviation', 'RFP max', 'RFP min', 'CY5 intensity mean', 'CY5 standard deviation', 'CY5 max', 'CY5 min', 'DAPI intensity mean', 'DAPI standard deviation', 'DAPI max', 'DAPI min', 'Note'});

            if contains(labelname, 'RFP')
                app.StateLabel.Text = ['Spheroid area (pixels): ', num2str(spheroid_pixels), newline, ...
                                       'Spheroid area (microns^2): ', num2str(spheroid_area), newline, ...
                                       'Diameter (pixels): ', num2str(diameter), newline, ...
                                       'Diameter (microns): ', num2str(diameter_microns), newline, ...
                                       'Perimeter (pixels): ', num2str(perimeter), newline, ...
                                       'Perimeter (microns): ', num2str(perimeter_microns), newline, ...
                                       'Circularity: ', num2str(circularity), newline, ...
                                       'RFP intensity mean: ', num2str(RFP_mean), newline, ...
                                       'RFP standard deviation: ', num2str(RFP_std), newline, ...
                                       'RFP max: ', num2str(RFP_max), newline, ...
                                       'RFP min: ', num2str(RFP_min), newline];
       

            elseif contains(labelname, 'CY5')
                app.StateLabel.Text = ['Spheroid area (pixels): ', num2str(spheroid_pixels), newline, ...
                                       'Spheroid area (microns^2): ', num2str(spheroid_area), newline, ...
                                       'Diameter (pixels): ', num2str(diameter), newline, ...
                                       'Diameter (microns): ', num2str(diameter_microns), newline, ...
                                       'Perimeter (pixels): ', num2str(perimeter), newline, ...
                                       'Perimeter (microns): ', num2str(perimeter_microns), newline, ...
                                       'Circularity: ', num2str(circularity), newline, ...
                                       'CY5 intensity mean: ', num2str(CY5_mean), newline, ...
                                       'CY5 standard deviation: ', num2str(CY5_std), newline, ...
                                       'CY5 max: ', num2str(CY5_max), newline, ...
                                       'CY5 min: ', num2str(CY5_min), newline];

           elseif contains(labelname, 'DAPI')
                app.StateLabel.Text = ['Spheroid area (pixels): ', num2str(spheroid_pixels), newline, ...
                                       'Spheroid area (microns^2): ', num2str(spheroid_area), newline, ...
                                       'Diameter (pixels): ', num2str(diameter), newline, ...
                                       'Diameter (microns): ', num2str(diameter_microns), newline, ...
                                       'Perimeter (pixels): ', num2str(perimeter), newline, ...
                                       'Perimeter (microns): ', num2str(perimeter_microns), newline, ...
                                       'Circularity: ', num2str(circularity), newline, ...
                                       'DAPI intensity mean: ', num2str(DAPI_mean), newline, ...
                                       'DAPI standard deviation: ', num2str(DAPI_std), newline, ...
                                       'DAPI max: ', num2str(DAPI_max), newline, ...
                                       'DAPI min: ', num2str(DAPI_min), newline];
            
            else
                app.StateLabel.Text = ['Spheroid area (pixels): ', num2str(spheroid_pixels), newline, ...
                                   'Spheroid area (microns^2): ', num2str(spheroid_area), newline, ...
                                   'Diameter (pixels): ', num2str(diameter), newline, ...
                                   'Diameter (microns): ', num2str(diameter_microns), newline, ...
                                   'Perimeter (pixels): ', num2str(perimeter), newline, ...
                                   'Perimeter (microns): ', num2str(perimeter_microns), newline, ...
                                   'Circularity: ', num2str(circularity), newline];
            end
            
            app.MLimage.ImageSource = app.format_img(spheroid_probmap);
            
            % caltsiumisensor, ligandi aktiivsus
            % keskm lokaalne standardhalve
            
            % Graph PDF
            current_img = double(imread(app.filenames_data(app.img_index).FilePath)); % linearizes
            sphval = current_img(mask);  % apply mask to current img, get spheroid data

            [f, xi] = ksdensity(sphval);
            plot(app.UIAxes1, xi, f);
            title(app.UIAxes1, 'PDF of current spheroid');
            xlabel(app.UIAxes1, 'Pixel Intensity');
            ylabel(app.UIAxes1, 'Probability Density');
        end

        % Value changed function: ProbabilitythresholdSlider
        function ProbabilitythresholdSliderValueChanged(app, event)
            value = app.ProbabilitythresholdSlider.Value;
            UpdateButtonPushed(app, event)
        end

        % Button pushed function: WritevaluestoresultsfileButton
        function WritevaluestoresultsfileButtonPushed(app, event)
            
            label = app.ImageLabel.Text;
            [~, labelname, ~] = fileparts(label);
            
            [BF_filename, BF_index] = match_to_BF(labelname, app.filenames_data);   % finds the index of BF file in filenames_data
            bfindex = find(app.output_table.Filename == string(BF_filename));       % finds the index of BF file in output_table
            
            AddanotetocurrentimageEditFieldValueChanged(app, event)                 % updates note
            app.note = string(app.AddanotetocurrentimageEditField.Value);
            app.analysis{1, 'Note'} = app.note;

            if ~isempty(bfindex) % If it's a file with a corresponding BF image, add extra data

                if contains(labelname, 'RFP')
                    
                    app.output_table{bfindex, 'RFP intensity mean'} = app.analysis{1, 'RFP intensity mean'};
                    app.output_table{bfindex, 'RFP standard deviation'} = app.analysis{1, 'RFP standard deviation'};
                    app.output_table{bfindex, 'RFP max'} = app.analysis{1, 'RFP max'};
                    app.output_table{bfindex, 'RFP min'} = app.analysis{1, 'RFP min'};
    
                elseif contains(labelname, 'CY5')
                    
                    app.output_table{bfindex, 'CY5 intensity mean'} = app.analysis{1, 'CY5 intensity mean'};
                    app.output_table{bfindex, 'CY5 standard deviation'} = app.analysis{1, 'CY5 standard deviation'};
                    app.output_table{bfindex, 'CY5 max'} = app.analysis{1, 'CY5 max'};
                    app.output_table{bfindex, 'CY5 min'} = app.analysis{1, 'CY5 min'};
    
                elseif contains(labelname, 'DAPI')
                    
                    app.output_table{bfindex, 'DAPI intensity mean'} = app.analysis{1, 'DAPI intensity mean'};
                    app.output_table{bfindex, 'DAPI standard deviation'} = app.analysis{1, 'DAPI standard deviation'};
                    app.output_table{bfindex, 'DAPI max'} = app.analysis{1, 'DAPI max'};
                    app.output_table{bfindex, 'DAPI min'} = app.analysis{1, 'DAPI min'};
                end
            
            else
                app.output_table = [app.output_table; app.analysis]; % No entry yet, just append
                app.AddanotetocurrentimageEditField.Value = "";      % Clear previous note
            end
            
            disp(app.output_table)
            NextButtonPushed(app, event)
        end

        % Button pushed function: ExportresultsButton
        function ExportresultsButtonPushed(app, event)
            
            results_folder = fullfile(app.folder_path, 'Results');
            
            if ~isfolder(results_folder)
                mkdir(results_folder);
            end
            
            [results_filename, results_folder, choice_index] = uiputfile({'*.xls', '.xls'; '*.csv', '.csv'},'Save file as', results_folder);

            if isequal(results_filename, 0) || isequal(results_folder, 0) % user pressed "cancel"
                uialert(app.UIFigure, 'Saving cancelled, please try again', 'Data loss warning', 'Icon', 'none');
                app.StateLabel.Text = 'Retry exporting.';
                return;
            end
            
            [~, ~, ext] = fileparts(results_filename);
            valid_extensions = {'.xls', '.csv'};

            if ~ismember(ext, valid_extensions)
                errordlg(['Invalid file extension ', ext, '. Please save as .xls or .csv'], 'File type error');
                app.StateLabel.Text = 'Retry exporting.';
                return;
            end
            
            % rounding of values
            columns_to_round = {'Area(px)', 'Area(microns)', 'Diameter(px)', 'Diameter(microns)', 'Perimeter(px)','Perimeter(microns)'};
            app.output_table{:, columns_to_round} = round(app.output_table{:, columns_to_round});

            results_filepath = fullfile(results_folder, results_filename);
            writetable(app.output_table, results_filepath)
            
            if choice_index == 1
                uialert(app.UIFigure, '✔ Exported as an Excel file', 'Success', 'Icon', 'none');
            else
                uialert(app.UIFigure, '✔ Exported as CSV', 'Success', 'Icon', 'none');
            end
            
        end

        % Value changed function: FillHolesCheckBox
        function FillHolesCheckBoxValueChanged(app, event)
            value = app.FillHolesCheckBox.Value;
            UpdateButtonPushed(app, event)
        end

        % Button pushed function: AutomaticanalysisButton
        function AutomaticanalysisButtonPushed(app, event)
            
            % With the set threshold, analyses the whole folder - assumes ML analysis is done beforehand
            app.img_index = 1;
            
            for n = 1:length(app.filenames_data(:))
                WritevaluestoresultsfileButtonPushed(app, event)
                UpdateButtonPushed(app, event)
                pause(0.05)
            end
            
            pause(2)
            ExportresultsButtonPushed(app, event)
        end

        % Value changed function: AddanotetocurrentimageEditField
        function AddanotetocurrentimageEditFieldValueChanged(app, event)
            value = app.AddanotetocurrentimageEditField.Value;
            disp(value)
        end

        % Value changed function: PredictionforListBox
        function PredictionforListBoxValueChanged(app, event)
            value = app.PredictionforListBox.Value;
            UpdateButtonPushed(app, event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            app.ExportresultsButton = uibutton(app.UIFigure, 'push');
            app.ExportresultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportresultsButtonPushed, true);
            app.ExportresultsButton.Text = 'Export results';
            app.WritevaluestoresultsfileButton = uibutton(app.UIFigure, 'push');
            app.WritevaluestoresultsfileButton.ButtonPushedFcn = createCallbackFcn(app, @WritevaluestoresultsfileButtonPushed, true);
            app.WritevaluestoresultsfileButton.Text = 'Write values to results file';
            app.RunMLmodelButton = uibutton(app.UIFigure, 'push');
            app.RunMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunMLmodelButtonPushed, true);
            app.RunMLmodelButton.Text = 'Run ML model';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.StateLabel = uilabel(app.UIFigure);
            app.StateLabel.Text = 'State';
            app.Image = uiimage(app.UIFigure);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ChannelLabel = uilabel(app.UIFigure);
            app.ChannelLabel.Text = 'Channel ';
            app.ChannelListBox = uilistbox(app.UIFigure);
            app.ChannelListBox.Items = {'Bright Field'};
            app.ChannelListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelListBoxValueChanged, true);
            app.ChannelListBox.Value = 'Bright Field';
            app.ZplaneListBox = uilistbox(app.UIFigure);
            app.ZplaneListBox.Items = {'0'};
            app.ZplaneListBox.ValueChangedFcn = createCallbackFcn(app, @ZplaneListBoxValueChanged, true);
            app.ZplaneListBox.Value = '0';
            app.WellLabel = uilabel(app.UIFigure);
            app.WellLabel.Text = 'Well ';
            app.WellListBox = uilistbox(app.UIFigure);
            app.WellListBox.Items = {'1'};
            app.WellListBox.ValueChangedFcn = createCallbackFcn(app, @WellListBoxValueChanged, true);
            app.WellListBox.Value = '1';
            app.ZplaneLabel = uilabel(app.UIFigure);
            app.ZplaneLabel.Text = 'Z-plane ';
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Visible = 'off';
            app.UpdateButton.Text = 'Update';
            app.MLimage = uiimage(app.UIFigure);
            app.MLlabel = uilabel(app.UIFigure);
            app.MLlabel.Text = 'Probability map';
            app.Binlabel = uilabel(app.UIFigure);
            app.Binlabel.Text = 'Area analysed';
            app.ProbabilitythresholdSliderLabel = uilabel(app.UIFigure);
            app.ProbabilitythresholdSliderLabel.Text = 'Probability threshold (%)';
            app.ProbabilitythresholdSlider = uislider(app.UIFigure);
            app.ProbabilitythresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ProbabilitythresholdSliderValueChanged, true);
            app.AddanotetocurrentimageEditField = uieditfield(app.UIFigure, 'text');
            app.AddanotetocurrentimageEditField.ValueChangedFcn = createCallbackFcn(app, @AddanotetocurrentimageEditFieldValueChanged, true);
            app.FillHolesCheckBox = uicheckbox(app.UIFigure);
            app.FillHolesCheckBox.ValueChangedFcn = createCallbackFcn(app, @FillHolesCheckBoxValueChanged, true);
            app.FillHolesCheckBox.Text = 'FillHoles';
            app.FillHolesCheckBox.Value = true;
            app.AutomaticanalysisButton = uibutton(app.UIFigure, 'push');
            app.AutomaticanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticanalysisButtonPushed, true);
            app.AutomaticanalysisButton.Text = 'Automatic analysis';
            app.AddanoteLabel = uilabel(app.UIFigure);
            app.AddanoteLabel.Text = 'Add a note to current image:';
            app.LabelstoshowListBoxLabel = uilabel(app.UIFigure);
            app.LabelstoshowListBoxLabel.Text = 'Prediction for';
            app.PredictionforListBox = uilistbox(app.UIFigure);
            app.PredictionforListBox.Items = {'Label 1'};
            app.PredictionforListBox.ValueChangedFcn = createCallbackFcn(app, @PredictionforListBoxValueChanged, true);
            app.PredictionforListBox.Value = 'Label 1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpheraSense

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end