classdef app_one < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        BrowsefolderButton  matlab.ui.control.Button
        ZplaneListBoxLabel  matlab.ui.control.Label
        WellDropDown        matlab.ui.control.DropDown
        WellDropDownLabel   matlab.ui.control.Label
        ZplaneListBox       matlab.ui.control.ListBox
        ImageLabel          matlab.ui.control.Label
        Image               matlab.ui.control.Image
        ErrorLabel          matlab.ui.control.Label
        PreviousButton      matlab.ui.control.Button
        NextButton          matlab.ui.control.Button
    end

    properties (Access = private)
        img_index = 1;
        folder_path = '';
        filenames = '';
        filenames_data = struct;
    end
    
    methods (Access = private)

        % Image format converstion: uint16, grayscale -> uint8, RGB
        function img_formatted = reformat_img(app, img)
           
            img_uint8 = im2uint8(img);
            
            % if grayscale
            if size(img_uint8, 3) == 1
                img_formatted = cat(3, img_uint8, img_uint8, img_uint8); 

            else
                img_formatted = img_uint8;

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            
            app.folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BAKATÖÖ');
            app.filenames = dir(fullfile(app.folder_path, '*.tif'));
            nr_images = numel(app.filenames);
            
            % 'F2_03_1_1Z0_Bright Field_002.tif';
            % <WellID>_<Read index>_<Channel index>_<Z index>_<Channel name>_<cycle index>.tif
            
            pattern = '(?<WellID>\w+)_(?<ReadIndex>\d+)_(?<ChannelIndex>\d+)_(?<ZIndex>\w+)_(?<ChannelName>[\w\s]+)_(?<CycleIndex>\d+)';
            app.filenames_data = struct('WellID', {}, 'ReadIndex', {}, 'ChannelIndex', {}, 'ZIndex', {}, 'ChannelName', {}, 'CycleIndex', {});
            
            % Read in file name data into table filenames_data
            for n = 1:nr_images
                f = app.filenames(n).name;
                file_data = regexp(f, pattern, 'names');
                app.filenames_data(n) = file_data;
            end
            
            image1 = imread(app.filenames(1).name);
            img_formatted = app.reformat_img(image1);

            app.Image.ImageSource = img_formatted;
            app.ImageLabel.Text = app.filenames(1).name;
            app.ErrorLabel.Text = "";

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.img_index == numel(app.filenames)

                % if user has checked all images
                % next activity

            else
                
                app.img_index = app.img_index + 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_next = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.reformat_img(image_next);
    
                app.Image.ImageSource = img_formatted;

            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            if app.img_index == 1

                app.ErrorLabel.Text = "Index = 1, can't go further back";

            else
                app.img_index = app.img_index - 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_prev = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.reformat_img(image_prev);
    
                app.Image.ImageSource = img_formatted;                           
            end
        end

        % Value changed function: WellDropDown
        function WellDropDownValueChanged(app, event)

            % Load the first image of specified well
            z_col = {app.filenames_data.ZIndex};
            wells = extractBefore(z_col, 2); % Well nr is first char of Zindex values
            well_indices = [];

            value = app.WellDropDown.Value;
            
            for i = 1:length(wells)
                well = wells{i};
                if strcmp(well,value)
                    well_indices = [well_indices i]; % Get indices of all images of specified well
                end
            
            end
            
            app.img_index = well_indices(1);

            image_well = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
            img_formatted = app.reformat_img(image_well);
    
            app.Image.ImageSource = img_formatted;
            app.ImageLabel.Text = app.filenames(app.img_index).name;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = ['MATLAB' newline 'App'];
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error';
            app.Image = uiimage(app.UIFigure);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ZplaneListBox = uilistbox(app.UIFigure);
            app.ZplaneListBox.Items = {'Z0', 'Z1', 'Z2', 'Z3', 'Z4'};
            app.ZplaneListBox.Value = 'Z0';
            app.WellDropDownLabel = uilabel(app.UIFigure);
            app.WellDropDownLabel.Text = 'Well';
            app.WellDropDown = uidropdown(app.UIFigure);
            app.WellDropDown.Items = {'1', '2', '3', '4'};
            app.WellDropDown.ValueChangedFcn = createCallbackFcn(app, @WellDropDownValueChanged, true);
            app.WellDropDown.Value = '1';
            app.ZplaneListBoxLabel = uilabel(app.UIFigure);
            app.ZplaneListBoxLabel.Text = 'Z-plane';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = ['Browse' newline 'folder'];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_one

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end