classdef app_two < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        RandomprogressButton  matlab.ui.control.Button
        ProgressbarLabel      matlab.ui.control.Label
        BrowsefolderButton    matlab.ui.control.Button
        ZplaneListBoxLabel    matlab.ui.control.Label
        WellListBox           matlab.ui.control.ListBox
        WellListBoxLabel      matlab.ui.control.Label
        ZplaneListBox         matlab.ui.control.ListBox
        ImageLabel            matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        ErrorLabel            matlab.ui.control.Label
        PreviousButton        matlab.ui.control.Button
        NextButton            matlab.ui.control.Button
        RunMLmodelButton      matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    properties (Access = private)
        folder_path = '';
        filenames_data = struct;
        img_index = 1;
    end
    
    methods (Access = private)
        
        function img_formatted = format_img(~, img)
           % Image format conversion for display

           % uint16 -> uint8
            if isa(img, 'uint16')
                img_uint8 = uint8(double(img) / double(max(img(:))) * 255);     
            else
                img_uint8 = im2uint8(img); % catch-all for other datatypes
            end
            
            % grayscale -> RGB
            if size(img_uint8, 3) == 1
                img_formatted = repmat(img_uint8, [1 1 3]);
            else
                img_formatted = img_uint8;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowsefolderButton
        function BrowsefolderButtonPushed(app, event)
            % Select folder

            app.folder_path = uigetdir('C:\Users\Kasutaja\Desktop\BSc_thesis');

            if app.folder_path == 0   % User canceled folder selection
                app.ErrorLabel.Text = 'No folder selected.';
                return;
            end
            
            % Get filenames_data (sorted alphanumerically)
            % At this stage, move all sketchy files into a separate folder

            app.filenames_data = dataset_handling(app.folder_path);

            % Load in well options
            
            wellIDs = {app.filenames_data.WellID};
            uniqueIDs = unique(wellIDs, 'stable');
            app.WellListBox.Items = cellstr(string(uniqueIDs));

            % Load in all Zplane options (initially)

            zplane_values = unique(cell2mat({app.filenames_data.ZPlane}));
            app.ZplaneListBox.Items = string(zplane_values);

            % Display first well of folder

            image1 = imread(app.filenames_data(1).FilePath);
            img_formatted = app.format_img(image1);
            app.Image.ImageSource = img_formatted;

            displayname = split(app.filenames_data(1).FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname;
            app.ErrorLabel.Text = "";
        end

        % Value changed function: WellListBox
        function WellListBoxValueChanged(app, event)
            
            value = app.WellListBox.Value;
            wellIDs = {app.filenames_data.WellID};
            well_selected = app.filenames_data(strcmp(wellIDs, value));

            % Load in Zplane options for specified well
            zplane_values = unique(cell2mat({well_selected.ZPlane}));
            app.ZplaneListBox.Items = string(zplane_values);
            
            % Display first image of specified well
            img = imread(well_selected(1).FilePath);
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            displayname = split(well_selected(1).FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname;
        end

        % Value changed function: ZplaneListBox
        function ZplaneListBoxValueChanged(app, event)

            % Stay on the same well indexes but change Z index
            
            well_value = app.WellListBox.Value;
            wellIDs = {app.filenames_data.WellID};
            well_selected = app.filenames_data(strcmp(wellIDs, well_value));

            z_value = app.ZplaneListBox.Value;
            %z_selected = well_selected([well_selected.ZPlane] == z_value);
            
            zplanes = {well_selected.ZPlane};
            z_selected = well_selected(strcmp(zplanes, z_value));

            % Update display

            img = imread(z_selected.FilePath); % get too few or too many inputs?
            img_formatted = app.format_img(img);
            app.Image.ImageSource = img_formatted;

            displayname = split(z_selected.FilePath, filesep);
            displayname = displayname{end};

            app.ImageLabel.Text = displayname; 

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            if app.img_index == numel(app.filenames_data)

                % if user has checked all images
                % next activity

            else
                
                app.img_index = app.img_index + 1;
                app.ImageLabel.Text = app.filenames_data(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_next = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_next);
    
                app.Image.ImageSource = img_formatted;

            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            
            if app.img_index == 1

                app.ErrorLabel.Text = "Index = 1, can't go further back";

            else
                app.img_index = app.img_index - 1;
                app.ImageLabel.Text = app.filenames(app.img_index).name;
                app.ErrorLabel.Text = "";
                
                image_prev = imread(fullfile(app.folder_path, app.filenames(app.img_index).name));
                img_formatted = app.format_img(image_prev);
    
                app.Image.ImageSource = img_formatted;                           
            end
        end

        % Button pushed function: RunMLmodelButton
        function RunMLmodelButtonPushed(app, event)

            % Later: make choosing Ilastik ML model dynamic
            ilastik_proj_path = 'C:\Users\Kasutaja\Desktop\BSc_thesis\testrun\draft1.ilp';

%             To-Fix: if no folder chosen, continues to run and causes errors later?
%             if ~isempty(app.folder_path)
%                 run_ilastik(app.folder_path, ilastik_proj_path)
%             else
%                 app.ErrorLabel.Text = "Choose a folder to analyse";
%             end

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Processing', 'Message', 'Starting Ilastik...');
            run_ilastik(app.folder_path, ilastik_proj_path, progress)
            

        end

        % Button pushed function: RandomprogressButton
        function RandomprogressButtonPushed(app, event)

            % Progress: [▰▰▰▱▱▱▱▱▱] 30%   

            progress = uiprogressdlg(app.UIFigure, 'Title', 'Processing', 'Message', 'Starting...');
            n= 50;

            for i = 1:n
                % Update progress
                progress.Value = i / n;
                progress.Message = sprintf('Processing image %d of %d...', i, n);
                pause(0.1); % Simulate work
            end
            close(progress);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunMLmodelButton = uibutton(app.UIFigure, 'push');
            app.RunMLmodelButton.ButtonPushedFcn = createCallbackFcn(app, @RunMLmodelButtonPushed, true);
            app.RunMLmodelButton.Text = 'Run ML model';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = 'Error';
            app.Image = uiimage(app.UIFigure);
            app.ImageLabel = uilabel(app.UIFigure);
            app.ImageLabel.Text = 'Image';
            app.ZplaneListBox = uilistbox(app.UIFigure);
            app.ZplaneListBox.Items = {'0'};
            app.ZplaneListBox.ValueChangedFcn = createCallbackFcn(app, @ZplaneListBoxValueChanged, true);
            app.ZplaneListBox.Value = '0';
            app.WellListBoxLabel = uilabel(app.UIFigure);
            app.WellListBoxLabel.Text = 'Well';
            app.WellListBox = uilistbox(app.UIFigure);
            app.WellListBox.Items = {'1'};
            app.WellListBox.ValueChangedFcn = createCallbackFcn(app, @WellListBoxValueChanged, true);
            app.WellListBox.Value = '1';
            app.ZplaneListBoxLabel = uilabel(app.UIFigure);
            app.ZplaneListBoxLabel.Text = 'Z-plane';
            app.BrowsefolderButton = uibutton(app.UIFigure, 'push');
            app.BrowsefolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsefolderButtonPushed, true);
            app.BrowsefolderButton.Text = 'Browse folder';
            app.ProgressbarLabel = uilabel(app.UIFigure);
            app.ProgressbarLabel.Text = 'Progressbar';
            app.RandomprogressButton = uibutton(app.UIFigure, 'push');
            app.RandomprogressButton.ButtonPushedFcn = createCallbackFcn(app, @RandomprogressButtonPushed, true);
            app.RandomprogressButton.Text = 'Randomprogress';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_two

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end